classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        ImageProcessingLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UploadImageButton     matlab.ui.control.Button
        NoiseButton           matlab.ui.control.Button
        RGB2GRAYButton        matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        RemoveNoiseButton     matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Button pushed function: TakeanImageButton
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);

        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            global a;
            global J;
            J=a;
            J =imnoise(J,'salt & pepper', 0.4);
            imshow(J,'Parent',app.UIAxes);
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            % Button pushed function: ImageafterNoiseRemovalButton
            global J;
            p=medfilt3(J,[5,5,3]);
            imshow(p,'Parent',app.UIAxes);
        end

        % Button pushed function: RGB2GRAYButton
        function RGB2GRAYButtonPushed(app, event)
            global a;
            RGB = a;
            p = rgb2gray(RGB);
            imshow(p,'Parent',app.UIAxes);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app3;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.ImageProcessingLabel = uilabel(app.UIFigure);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'OImage')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.NoiseButton = uibutton(app.UIFigure, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.RGB2GRAYButton = uibutton(app.UIFigure, 'push');
            app.RGB2GRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RGB2GRAYButtonPushed, true);
            app.RGB2GRAYButton.Text = 'RGB2GRAY';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end