classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        EdgeingTab              matlab.ui.container.Tab
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes6_2               matlab.ui.control.UIAxes
        SegmentTab              matlab.ui.container.Tab
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes3_2               matlab.ui.control.UIAxes
        SegmentndSeperateTab    matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes4_4               matlab.ui.control.UIAxes
        UIAxes4_3               matlab.ui.control.UIAxes
        UIAxes4_5               matlab.ui.control.UIAxes
        UIAxes4_2               matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        EdgeDetectionButton     matlab.ui.control.Button
        SegmentationLabel       matlab.ui.control.Label
        Segment_seperateButton  matlab.ui.control.Button
        SegmentButton           matlab.ui.control.Button
        EdegingLabel            matlab.ui.control.Label
        Spinner                 matlab.ui.control.Spinner
        ImageProcessingLabel    matlab.ui.control.Label
        Button_5                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Segment_seperateButton
        function Segment_seperateButtonPushed(app, event)
            
            % MATLAB code for
            % Separate the brain part from MRI image.
            
            % read the mri image.
            k = imread('D:/FET/Project_400s1/DIMP/New folder/head.png');
            
            
            % display the image.
            imshow(k, 'parent', app.UIAxes4_5);
            
            % convert it into binary image.
            k1=im2bw(k,graythresh(k));
            
            % display the binary image.
             imshow(k1, 'parent', app.UIAxes4_4);
            
            % Make the brain largest connected component.
            % We need to apply opening operation.
            % define the structuring element.
            SE=strel('disk',7,4);
            
            % apply the opening operation.
            k2=imopen(k1,SE);
            
            % display the image now.
             imshow(k2, 'parent', app.UIAxes4_3);
            
            % apply connected component analysis.
            b=bwlabel(k2);
            
            % display the colored map image.
             imshow(b, 'parent', app.UIAxes4_2);
            
            % brain is component labeled as 9.
            % set all other component as 0 except brain.
            
            b(b~=8)=0;
            
            % display the brain part.
             imshow(b, 'parent', app.UIAxes4);
            
            % inside the brain part, black portion is there.
            % close the black pixels inside brain part.
          
            k3=imclose(b,strel('disk',9));
            
            % display the brain part.
             %imshow(k3, 'parent', app.UIAxes4);
            
            % extract the brain from original image.
           
            k4=k3.*double(k);
            
            % display the real brain from original image.
            
             imshow(k4, 'parent', app.UIAxes);
            
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            
            I = imread('D:/FET/Project_400s1/DIMP/New folder/head.png'); 
            LF = rgb2gray(I);
            LF=edge(LF,'log'); 
            imshow(I, 'parent', app.UIAxes6);
            imshow(LF, 'parent', app.UIAxes6_2);
        end

        % Size changed function: EdgeingTab
        function EdgeingTabSizeChanged(app, event)
         
            
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
              I = im2double(imread('D:/FET/Project_400s1/DIMP/New folder/head.png'));
              x=198; y=359;
              %J = regiongrowing(I,x,y,0.2); 
              %figure, imshow(I+J);
            
            % Author: D. Kroon, University of Twente
            if(exist('reg_maxdist','var')==0), reg_maxdist=0.2; end
            if(exist('y','var')==0)
               [y,x]=getpts; y=round(y(1)); x=round(x(1)); end
            J = zeros(size(I)); % Output 
            Isizes = size(I); % Dimensions of input image
            reg_mean = I(x,y); % The mean of the segmented region
            reg_size = 1; % Number of pixels in region
            % Free memory to store neighbours of the (segmented) region
            neg_free = 10000; neg_pos=0;
            neg_list = zeros(neg_free,3); 
            pixdist=0; % Distance of the region newest pixel to the regio mean
            % Neighbor locations (footprint)
            neigb=[-1 0; 1 0; 0 -1;0 1];
            % Start regiogrowing until distance between regio and posible new pixels become
            % higher than a certain treshold
            while(pixdist<reg_maxdist&&reg_size<numel(I))
                % Add new neighbors pixels
                for j=1:4
                    % Calculate the neighbour coordinate
                    xn = x +neigb(j,1); yn = y +neigb(j,2);
                    
                    % Check if neighbour is inside or outside the image
                    ins=(xn>=1)&&(yn>=1)&&(xn<=Isizes(1))&&(yn<=Isizes(2));
                    
                    % Add neighbor if inside and not already part of the segmented area
                    if(ins&&(J(xn,yn)==0)) 
                            neg_pos = neg_pos+1;
                            neg_list(neg_pos,:) = [xn yn I(xn,yn)]; J(xn,yn)=1;
                    end
                end
                % Add a new block of free memory
                if(neg_pos+10>neg_free), neg_free=neg_free+10000; neg_list((neg_pos+1):neg_free,:)=0; end
                
                % Add pixel with intensity nearest to the mean of the region, to the region
                dist = abs(neg_list(1:neg_pos,3)-reg_mean);
                [pixdist, index] = min(dist);
                J(x,y)=2; reg_size=reg_size+1;
                
                % Calculate the new mean of the region
                reg_mean= (reg_mean*reg_size + neg_list(index,3))/(reg_size+1);
                
                % Save the x and y coordinates of the pixel (for the neighbour add proccess)
                x = neg_list(index,1); y = neg_list(index,2);
                
                % Remove the pixel from the neighbour (check) list
                neg_list(index,:)=neg_list(neg_pos,:); neg_pos=neg_pos-1;
            end
            % Return the segmented area as logical matrix
            J=J>1;
             imshow(I-J, 'parent', app.UIAxes3_2);
             imshow(I, 'parent', app.UIAxes3);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            k = imread('D:/FET/Project_400s1/DIMP/New folder/head.png');
            k1=im2bw(k,graythresh(k));
            SE=strel('disk',7,4);
            k2=imopen(k1,SE);
            b=bwlabel(k2);
            b(b~=value)=-1;
            imshow(b, 'parent', app.UIAxes4);
            k3=imclose(b,strel('disk',9));
            k4=k3.*double(k);
            imshow(k4, 'parent', app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EdgeingTab = uitab(app.TabGroup);
            app.EdgeingTab.SizeChangedFcn = createCallbackFcn(app, @EdgeingTabSizeChanged, true);
            app.EdgeingTab.Title = 'Edgeing';
            app.UIAxes6 = uiaxes(app.EdgeingTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes6_2 = uiaxes(app.EdgeingTab);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.UIAxes3 = uiaxes(app.SegmentTab);
            title(app.UIAxes3, 'seged')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.SegmentTab);
            title(app.UIAxes3_2, 'seged')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.SegmentndSeperateTab = uitab(app.TabGroup);
            app.SegmentndSeperateTab.Title = 'Segment nd Seperate';
            app.UIAxes = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes, 'seg')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes4_4 = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, 'X')
            ylabel(app.UIAxes4_4, 'Y')
            app.UIAxes4_3 = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            app.UIAxes4_5 = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes4_5, 'Title')
            xlabel(app.UIAxes4_5, 'X')
            ylabel(app.UIAxes4_5, 'Y')
            app.UIAxes4_2 = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            app.UIAxes4 = uiaxes(app.SegmentndSeperateTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.Segment_seperateButton = uibutton(app.UIFigure, 'push');
            app.Segment_seperateButton.ButtonPushedFcn = createCallbackFcn(app, @Segment_seperateButtonPushed, true);
            app.Segment_seperateButton.Text = 'Segment_seperate';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.EdegingLabel = uilabel(app.UIFigure);
            app.EdegingLabel.Text = 'Edeging';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ImageProcessingLabel = uilabel(app.UIFigure);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '<';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end